classdef Virtual_CT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        CoosePatientDropDown       matlab.ui.control.DropDown
        CoosePatientDropDownLabel  matlab.ui.control.Label
        ROIDropDown                matlab.ui.control.DropDown
        ROIDropDownLabel           matlab.ui.control.Label
        DetectionDropDown          matlab.ui.control.DropDown
        DetectionDropDownLabel     matlab.ui.control.Label
        FrameNoDropDown            matlab.ui.control.DropDown
        FrameNoDropDownLabel       matlab.ui.control.Label
        Pause                      matlab.ui.control.Button
        FrameNoEditField           matlab.ui.control.NumericEditField
        FrameNoEditFieldLabel      matlab.ui.control.Label
        EnterFPSEditField          matlab.ui.control.NumericEditField
        EnterFPSEditFieldLabel     matlab.ui.control.Label
        Previous                   matlab.ui.control.Button
        Next                       matlab.ui.control.Button
        Double_previous            matlab.ui.control.Button
        Double_Next                matlab.ui.control.Button
        NewROIButton               matlab.ui.control.Button
        ResetButton                matlab.ui.control.Button
        UITable                    matlab.ui.control.Table
        ChooseSerisDropDown        matlab.ui.control.DropDown
        ChooseSerisDropDownLabel   matlab.ui.control.Label
        UIAxes2                    matlab.ui.control.UIAxes
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = public)

        
        vDSA= Analysys_CT();
        UTIL_Dialog = Dialog_box();
        UTIL_Load = Load_Box();
        UTIL_freq = Time_Frequency();
        UTIL = utilities();
        
        arr_s_buffer_startupFcn= [];
        sChosen_Seris =[];
        g_Counter_mat_detection = 1;
        Load_analysis_handle = [];
        PlotImage=struct();
        step =0.2; % 5 fps by default
        bFlag_interpulation = true;
        m_sFolerPlotName;
        m_bPlotFlag = 1;
        
    end
    
    methods (Access = private)
        
        function results = ROI_Of_Dicom(app)

                
        app.vDSA.Img_present_roi = app.vDSA.mat_sdicom_Images{1,1}*25;
            switch app.vDSA.App_Chosen_seris 
                case 'HEAD PERFUSION'
                    app.ROI_HEAD_PERFUSION();
                case 'original'
                    app.ROI_original();
                otherwise
                    app.ROI_Default();

            end
            
           % app.vDSA.vec_ROI_pos_data=app.vDSA.Draw_ROI();

            %% Create a button with option to save the analysis data
           % while(app.vDSA.count_analysys_img ~= app.vDSA.iRow_Location_in_table && app.vDSA.bIs_Foler_empty)
           % 
           % app.vDSA.ROI_Mask = app.vDSA.Get_Mask_img();
           % app.vDSA.Global_image_threshold= app.vDSA.Get_optimum_Thresh();
           % app.Edge_Detection();
           % [app.vDSA.sNew_Dicom_file_folder,app.vDSA.bIs_Foler_empty]=app.vDSA.Create_Sorted_Dicom_Files_From_ROI();
           % app.vDSA.count_analysys_img = app.vDSA.count_analysys_img+1;
           % 
           % end

           % [app.vDSA.iX_Limit ,app.vDSA.iY_Limit] = app.vDSA.Get_Axis_ROI();
            % app.vDSA.count_analysys_img = 1;


            %     app.Load_analysis_handle=app.UTIL_Load.Get_Load_Box_handle();
            % while app.vDSA.count_analysys_img ~= app.vDSA.iRow_Location_in_table
            % 
            % 
            %     app.vDSA.ROI_Mask = app.vDSA.Get_Mask_img();
            %     app.vDSA.Global_image_threshold= app.vDSA.Get_optimum_Thresh();
            %     app.UTIL_Load.Porcess_load((app.vDSA.count_analysys_img/app.vDSA.iRow_Location_in_table),app.Load_analysis_handle,'Loading analyised ROI');
            %     app.Edge_Detection();
            %     app.vDSA.count_analysys_img = app.vDSA.count_analysys_img+1;
            % end
            % 
            % app.vDSA.count_analysys_img = 1;
            % app.UTIL_Load.Close_Load_ny_handle(app.Load_analysis_handle);
            % app.PlotImage.value = "Canny_Detection";

        end
        
        function results = Edge_Detection(app)

            switch app.vDSA.App_Chosen_seris 
                case 'HEAD PERFUSION'
                    app.vDSA=app.vDSA.HEAD_PERFUSION_Detection();
                    %%app.vDSA.table_Perfusion= app.vDSA.HEAD_PERFUSION_Detection();
                    app.vDSA.mat_Chosen_Edge_Detection{app.vDSA.count_analysys_img,1} = app.vDSA.Canny_Edge_Detection();
                     app.vDSA.iBlock_Counter = app.vDSA.iBlock_Counter +1;
                     if(app.vDSA.iBlock_Counter > app.vDSA.iRepiptive_frame_PERFUSION) % reset inculde the last frame
                        app.vDSA.iBlock_Counter =1 ;
                    end
                otherwise
                     app.vDSA.mat_Chosen_Edge_Detection{app.vDSA.count_analysys_img,1} = app.vDSA.Canny_Edge_Detection();
             end

            
        end
        
        function results = Plot(app)

            app.Plot_Properties();
            
            % 
            % directoryContents = dir(app.vDSA.sNew_Dicom_file_folder);
            % name = [app.vDSA.sNew_Dicom_file_folder,'\',directoryContents(3).name];
            % h=dicomread(directoryContents(3).name,"frames",1);
            % imshow(h,'Parent',app.UIAxes);
                app.Plot_Image();
        end


        function results = main_process(app)
            
           
            app.vDSA.Chosen_section_data = app.vDSA.Get_Chosen_Dicom_Section();
            app.UITableCellEdit();
            app.vDSA.Array_Dicom_Paths = app.vDSA.Get_All_Dicom_Path();
            [app.vDSA.mat_sdicom_Images,app.vDSA.arr_sNot_Sorted_Dicom_Files] =app.vDSA.Create_Dicom_Matrix();
            app.vDSA.arr_New_Sorted_Dicom_Images=app.vDSA.Sorted_Dicom_Img();
            [app.vDSA.iRow_Location_in_table,app.vDSA.icol_Location_in_table] = app.vDSA.Get_New_Sorted_Dicom_Dim();
            app.vDSA.mat_sdicom_Images = app.vDSA.Set_New_Sorted_Mat_Dicom();
            app.Process_after_Dicom_load();


        end
        
        function results = Process_after_Dicom_load(app)
            
            app.ROI_Of_Dicom();
          
            app.Plot();
        end
        
        function results = Plot_Properties(app)

            switch app.vDSA.App_Chosen_seris 
                case 'HEAD PERFUSION'
                    [app.vDSA.iX_Time_Vector,app.vDSA.iY_Mean_Intensity_vector] = app.vDSA.Get_Axis_Perfusion();
                    % if(isempty(app.vDSA.sFull_Path_chosen_seg__roi))
                    %  [app.vDSA.iX_Limit ,app.vDSA.iY_Limit] = app.vDSA.Get_Axis_ROI();
                    % end
                    %  app.UIAxes.XLim =app.vDSA.iX_Limit;
                    %  app.UIAxes.YLim = app.vDSA.iY_Limit;
                     app.UIAxes.Title.String = app.vDSA.App_Chosen_ROI;

                case 'original'
                    if(isempty(app.vDSA.sFull_Path_chosen_seg__roi))
                         [app.vDSA.iX_Limit ,app.vDSA.iY_Limit] = app.vDSA.Get_Axis_ROI();
                         app.UIAxes.XLim =app.vDSA.iX_Limit;
                         app.UIAxes.YLim = app.vDSA.iY_Limit;
                    end

                otherwise
                    if(isempty(app.vDSA.sFull_Path_chosen_seg__roi))
                         [app.vDSA.iX_Limit ,app.vDSA.iY_Limit] = app.vDSA.Get_Axis_ROI();
                         app.UIAxes.XLim =app.vDSA.iX_Limit;
                         app.UIAxes.YLim = app.vDSA.iY_Limit;
                         app.UIAxes.Title.String = app.vDSA.App_Chosen_ROI;
                    end
            end
            
        end


        function results = Plot_Image(app)

            switch  app.vDSA.App_Choose_Patient

                case 'F_1990'
                        switch app.vDSA.App_Chosen_seris 
                            case 'HEAD PERFUSION'


                                iTime_axis = 0:(app.vDSA.dStep_inteval_PERFUSION):30;
                                 dPixel_Intensities = interp1(app.vDSA.iX_Time_Vector, app.vDSA.iY_Mean_Intensity_vector,iTime_axis, 'linear');
                                 xlabel(app.UIAxes2,'Time (sec)');
                                 ylabel(app.UIAxes2,'Mean Intensity ')
                                 %xlim([0 30]);
                                 plot(app.UIAxes2,app.vDSA.iX_Time_Vector,app.vDSA.iY_Mean_Intensity_vector,'o',iTime_axis,dPixel_Intensities,':.');
                                 app.bFlag_interpulation = false;
            
                                 if(str2double(app.vDSA.iFrame_number_chosen)== 10 && app.m_bPlotFlag)
                                   figure('Visible', 'off');
                                   plot(app.vDSA.iX_Time_Vector,app.vDSA.iY_Mean_Intensity_vector,'o',iTime_axis,dPixel_Intensities,':.');
                                    title("ROI Perfusion Intensity");
                                    ylabel("Mean Pixel intensity");
                                    xlabel("Time [Sec]");
                                    legend('Fluid Circulation Blood Vessel');
                                    app.m_bPlotFlag = ~app.m_bPlotFlag;
                                  save(fullfile(app.UTIL.m_sFolderPath, strcat('Graph_mean_intensity_',app.vDSA.App_Chosen_ROI,'.mat')), 'dPixel_Intensities');
                                  save(fullfile(app.UTIL.m_sFolderPath, strcat('Graph_Time_',app.vDSA.App_Chosen_ROI,'.mat')), 'iTime_axis');
                                  disp('File saved: Graph_mean_intensity.mat and Graph_Time.mat');
                                  saveas(gcf,fullfile(app.UTIL.m_sFolderPath, strcat('Graph_full_plot_',app.vDSA.App_Chosen_ROI,'.fig')));
                                  saveas(gcf,fullfile(app.UTIL.m_sFolderPath, strcat('Graph_full_plot_',app.vDSA.App_Chosen_ROI,'.png')));
                                    close(gcf);
            
                                 end
            
                                
                            if(mod(app.g_Counter_mat_detection,23) == 10)

                                app.vDSA=app.vDSA.PlotArteryColorChanges();
                              imshowpair(app.vDSA.mat_Chosen_Edge_Detection{app.g_Counter_mat_detection,1}*25,app.vDSA.m_MatColorizedArtery*100,"blend",'Parent',app.UIAxes);
                            app.vDSA=app.vDSA.Get_registered_Image(app.g_Counter_mat_detection,app.UTIL.m_sFolderPath);
                            %imshowpair(app.vDSA.mat_Chosen_Edge_Detection{app.g_Counter_mat_detection,1}*25,app.vDSA.ROI_Mask*25,"diff",'Parent',app.UIAxes);
                            imshowpair(app.vDSA.mat_Chosen_Edge_Detection{app.g_Counter_mat_detection,1}*25,app.vDSA.m_matRegistered,"blend");
            
                                 if(app.g_Counter_mat_detection == 194 )

                                      title('Perfusion Angio Regestraion');
                                     ylabel("Mean Pixel intensity");
                                     xlabel("Matrix 512X512 uint16");
                                     %Save reg IMG
                                    saveas(gcf,fullfile(app.UTIL.m_sFolderPath, 'Perfusion_Angio_Regestraion.fig'));
                                    saveas(gcf,fullfile(app.UTIL.m_sFolderPath, 'Perfusion_Angio_Regestraion.png'));
                
                                    
                                     app.vDSA=app.vDSA.Get_Reff_Images(app.vDSA.App_Chosen_seris,app.g_Counter_mat_detection,app.UTIL.m_sFolderPath);
                                        figure;

                                        imshowpair(app.vDSA.mat_Chosen_Edge_Detection{app.g_Counter_mat_detection,1}*25,app.vDSA.ROI_Mask*25,"diff");
                                            title('ROI Prefusion Based Registration');
                                            ylabel("Mean Pixel intensity");
                                            xlabel("Matrix 512X512 uint16");
                                            saveas(gcf,fullfile(app.UTIL.m_sFolderPath, strcat('Analys_Data_',app.vDSA.App_Chosen_ROI,'.fig')));
                                            saveas(gcf,fullfile(app.UTIL.m_sFolderPath, strcat('Analys_Data_',app.vDSA.App_Chosen_ROI,'_png.png')));
                                        % subplot(3,2,1);
                                        %     imshowpair(app.vDSA.mat_Chosen_Edge_Detection{app.g_Counter_mat_detection,1}*25,app.vDSA.ROI_Mask*25,"diff");
                                        %     title('ROI Prefusion Based Angio');
                                        % 
                                        % subplot(3,2,2);
                                        %      imshowpair(app.vDSA.mat_Chosen_Edge_Detection{app.g_Counter_mat_detection,1}*25,app.vDSA.m_matRegistered,"blend");
                                        %      title('Registration Angio - Perfusion');
                                        % 
                                        % subplot(3,2,3);
                                        %     imshow(app.vDSA.m_matHead_Perfusion_REFF);
                                        %     title('Head Perfusion REFF');
                                        % 
                                        % subplot(3,2,4);
                                        %     imshow(app.vDSA.m_matANGIO_REFF);
                                        %     title('Angio REFF');
                                        % 
                                        % subplot(3,2,[5 6]);
                                        %     load(fullfile(app.UTIL.m_sFolderPath, strcat('Graph_mean_intensity_',app.vDSA.App_Chosen_ROI,'.mat')),'dPixel_Intensities');
                                        %     load(fullfile(app.UTIL.m_sFolderPath, strcat('Graph_Time_',app.vDSA.App_Chosen_ROI,'.mat')), 'iTime_axis');
                                        %     fig = openfig(fullfile(app.UTIL.m_sFolderPath, strcat('Graph_full_plot_',app.vDSA.App_Chosen_ROI,'.fig')));
                                        %     set(fig, 'Visible', 'on');
                                        %     plot(iTime_axis,dPixel_Intensities);
                                        %     title('PK - Perfusion Graph');
                                        % 
                                        % 
                                        % 
                                        %  saveas(gcf,fullfile(app.UTIL.m_sFolderPath, strcat('Analys_Data_',app.vDSA.App_Chosen_ROI,'.fig')));
                                        %   saveas(gcf,fullfile(app.UTIL.m_sFolderPath, strcat('Analys_Data_',app.vDSA.App_Chosen_ROI,'_png.png')));
                                        %   disp('File saved : Analys_Data.fig');
                                        %   disp('File saved : Analys_Data_png.png');
                                        % 
                                          close(gcf);
                
                                         % imshowpair(app.vDSA.mat_Chosen_Edge_Detection{app.g_Counter_mat_detection,1}*25,app.vDSA.ROI_Mask*25,"diff");
                                         % saveas(gcf,fullfile(app.UTIL.m_sFolderPath, strcat('ROI_',app.vDSA.App_Chosen_ROI,'.fig')));
                                         % 
                
                                        % saveas(gcf,'ROI_Prefusion_Based_Angio.png');
                                        % close(gcf);
                                 end
                            
                            end
                        
                            case 'ANGIO  F_0.7 iMAR'
                              imshowpair(app.vDSA.mat_Chosen_Edge_Detection{app.g_Counter_mat_detection,1}*25,app.vDSA.ROI_Mask*25,"diff",'Parent',app.UIAxes);
                                 if(app.g_Counter_mat_detection == 325)
                                        
                                   app.vDSA=app.vDSA.Get_Reff_Images(app.vDSA.App_Chosen_seris,app.g_Counter_mat_detection,app.UTIL.m_sFolderPath);

                                 end

                            case 'original'
                                
                                    imshow(app.vDSA.mat_Chosen_Edge_Detection{app.g_Counter_mat_detection,1}*20,'Parent',app.UIAxes);
            
                            otherwise
            
                                  imshowpair(app.vDSA.mat_Chosen_Edge_Detection{app.g_Counter_mat_detection,1},app.vDSA.ROI_Mask*25,"diff",'Parent',app.UIAxes);
            
                        
                                % switch app.PlotImage.value
                                % 
                                %         case "Canny_Detection"
                                % 
                                %              imshow(app.vDSA.mat_Chosen_Edge_Detection{app.g_Counter_mat_detection,1},'Parent',app.UIAxes);
                                % 
                                %          case "ROI_Mask"
                                %              imshow(app.vDSA.ROI_Mask,'Parent',app.UIAxes);
                                %              app.PlotImage.value = "Canny_Detection";% restore to default fix in the future
                                % 
                                % 
                                %   end
                        end
        
        
                case 'F_1980'
                     switch app.vDSA.App_Chosen_seris 
                            case 'HEAD PERFUSION'
            
                                iTime_axis = 0:(app.vDSA.dStep_inteval_PERFUSION):30;
                                 dPixel_Intensities = interp1(app.vDSA.iX_Time_Vector, app.vDSA.iY_Mean_Intensity_vector,iTime_axis, 'linear');
                                 xlabel(app.UIAxes2,'Time (sec)');
                                 ylabel(app.UIAxes2,'Mean Intensity ')
                                 %xlim([0 30]);
                                 plot(app.UIAxes2,app.vDSA.iX_Time_Vector,app.vDSA.iY_Mean_Intensity_vector,'o',iTime_axis,dPixel_Intensities,':.');
                                 app.bFlag_interpulation = false;
            
                                 if(str2double(app.vDSA.iFrame_number_chosen)== 8 && app.m_bPlotFlag)
                                   figure('Visible', 'off');
                                   plot(app.vDSA.iX_Time_Vector,app.vDSA.iY_Mean_Intensity_vector,'o',iTime_axis,dPixel_Intensities,':.');
                                    title("ROI Perfusion Intensity ");
                                    ylabel("Mean Pixel intensity");
                                    xlabel("Time [Sec]");
                                    app.m_bPlotFlag = ~app.m_bPlotFlag;
                                  save(fullfile(app.UTIL.m_sFolderPath, strcat('Graph_mean_intensity_',app.vDSA.App_Chosen_ROI,'.mat')), 'dPixel_Intensities');
                                  save(fullfile(app.UTIL.m_sFolderPath, strcat('Graph_Time_',app.vDSA.App_Chosen_ROI,'.mat')), 'iTime_axis');
                                  disp('File saved: Graph_mean_intensity.mat and Graph_Time.mat');
                                  saveas(gcf,fullfile(app.UTIL.m_sFolderPath, strcat('Graph_full_plot_',app.vDSA.App_Chosen_ROI,'.fig')));
                                  saveas(gcf,fullfile(app.UTIL.m_sFolderPath, strcat('Graph_full_plot_',app.vDSA.App_Chosen_ROI,'.png')));
                                    close(gcf);
            
                                 end
            
                            
                            if(mod(app.g_Counter_mat_detection,23) == 8)
                                
                                    app.vDSA=app.vDSA.Get_registered_Image(app.g_Counter_mat_detection,app.UTIL.m_sFolderPath);
                                    imshowpair(app.vDSA.mat_Chosen_Edge_Detection{app.g_Counter_mat_detection,1}*25,app.vDSA.ROI_Mask*25,"diff",'Parent',app.UIAxes);
                                     imshowpair(app.vDSA.mat_Chosen_Edge_Detection{app.g_Counter_mat_detection,1}*25,app.vDSA.m_matRegistered,"blend");
                                     
                                 if(app.g_Counter_mat_detection == 192 )

                                     title('Perfusion Angio Regestraion');
                                     ylabel("Mean Pixel intensity");
                                     xlabel("Matrix 512X512 uint16");
                                     %Save reg IMG
                                    saveas(gcf,fullfile(app.UTIL.m_sFolderPath, 'Perfusion_Angio_Regestraion.fig'));
                                    saveas(gcf,fullfile(app.UTIL.m_sFolderPath, 'Perfusion_Angio_Regestraion.png'));
                                    
                                     app.vDSA=app.vDSA.Get_Reff_Images(app.vDSA.App_Chosen_seris,app.g_Counter_mat_detection,app.UTIL.m_sFolderPath);
                                        % figure;
                                        % subplot(3,2,1);
                                            imshowpair(app.vDSA.mat_Chosen_Edge_Detection{app.g_Counter_mat_detection,1}*25,app.vDSA.ROI_Mask*25,"diff");
                                            title('ROI Prefusion Based Registration');
                                            ylabel("Mean Pixel intensity");
                                            xlabel("Matrix 512X512 uint16");
                                            saveas(gcf,fullfile(app.UTIL.m_sFolderPath, strcat('Analys_Data_',app.vDSA.App_Chosen_ROI,'.fig')));
                                            saveas(gcf,fullfile(app.UTIL.m_sFolderPath, strcat('Analys_Data_',app.vDSA.App_Chosen_ROI,'_png.png')));
                                            % 
                                            % if(~isempty(app.vDSA.m_matANGIO_REFF))% Cond: To start Registeration
                                            %     subplot(3,2,2);
                                            %     imshowpair(app.vDSA.mat_Chosen_Edge_Detection{app.g_Counter_mat_detection,1}*25,app.vDSA.m_matRegistered,"blend");
                                            %     title('Registration Angio - Perfusion');
                                            % end
                                        %subplot(3,2,3);
                                         %   imshow(app.vDSA.m_matHead_Perfusion_REFF);
                                          %  title('Head Perfusion REFF');
                
                                        %subplot(3,2,4);
                                            %imshow(app.vDSA.m_matANGIO_REFF);
                                            %title('Angio REFF');
                
                                        % subplot(3,2,[5 6]);
                                        %     load(fullfile(app.UTIL.m_sFolderPath, strcat('Graph_mean_intensity_',app.vDSA.App_Chosen_ROI,'.mat')),'dPixel_Intensities');
                                        %     load(fullfile(app.UTIL.m_sFolderPath, strcat('Graph_Time_',app.vDSA.App_Chosen_ROI,'.mat')), 'iTime_axis');
                                        %     plot(iTime_axis,dPixel_Intensities);
                                        %     title('PK - Perfusion Graph');
                                        % 
                
                
                                         % saveas(gcf,fullfile(app.UTIL.m_sFolderPath, strcat('Analys_Data_',app.vDSA.App_Chosen_ROI,'.fig')));
                                         %  saveas(gcf,fullfile(app.UTIL.m_sFolderPath, strcat('Analys_Data_',app.vDSA.App_Chosen_ROI,'_png.png')));
                                         %  disp('File saved : Analys_Data.fig');
                                         %  disp('File saved : Analys_Data_png.png');
                
                                          close(gcf);
                
                                         imshowpair(app.vDSA.mat_Chosen_Edge_Detection{app.g_Counter_mat_detection,1}*25,app.vDSA.ROI_Mask*25,"diff");
                                         saveas(gcf,fullfile(app.UTIL.m_sFolderPath, strcat('ROI_',app.vDSA.App_Chosen_ROI,'.fig')));
                
                
                                 end
                            end

                         case 'ANGIO  F_0.7 iMAR'
                              imshowpair(app.vDSA.mat_Chosen_Edge_Detection{app.g_Counter_mat_detection,1}*25,app.vDSA.ROI_Mask*25,"diff",'Parent',app.UIAxes);
                                 if(app.g_Counter_mat_detection == 348)
                                        
                                   app.vDSA=app.vDSA.Get_Reff_Images(app.vDSA.App_Chosen_seris,app.g_Counter_mat_detection,app.UTIL.m_sFolderPath);

                                 end
                            
                            %end
                                    
                            %%end
                                
                                %imshow(img_obj.CData,'Parent',app.UIAxes);
                               %imshow(app.vDSA.mat_Chosen_Edge_Detection{app.g_Counter_mat_detection,1}*25,'Parent',app.UIAxes);
                                %if(app.bFlag_interpulation)
                                    
                                %else
            %                        imshow(app.vDSA.mat_Chosen_Edge_Detection{app.g_Counter_mat_detection,1},'Parent',app.UIAxes);
             %                   end
                            case 'original'
                                
                                    imshow(app.vDSA.mat_Chosen_Edge_Detection{app.g_Counter_mat_detection,1}*20,'Parent',app.UIAxes);
            
                            otherwise
            
                                  imshowpair(app.vDSA.mat_Chosen_Edge_Detection{app.g_Counter_mat_detection,1},app.vDSA.ROI_Mask*25,"diff",'Parent',app.UIAxes);
            
                        
                     end

                    case 'M_1956'
                     switch app.vDSA.App_Chosen_seris 
                            case 'HEAD PERFUSION'
            
                                iTime_axis = 0:(app.vDSA.dStep_inteval_PERFUSION):30;
                                 dPixel_Intensities = interp1(app.vDSA.iX_Time_Vector, app.vDSA.iY_Mean_Intensity_vector,iTime_axis, 'linear');
                                 xlabel(app.UIAxes2,'Time (sec)');
                                 ylabel(app.UIAxes2,'Mean Intensity ')
                                 %xlim([0 30]);
                                 plot(app.UIAxes2,app.vDSA.iX_Time_Vector,app.vDSA.iY_Mean_Intensity_vector,'o',iTime_axis,dPixel_Intensities,':.');
                                 app.bFlag_interpulation = false;

                                 %imshowpair(app.vDSA.mat_Chosen_Edge_Detection{app.g_Counter_mat_detection,1}*25,app.vDSA.ROI_Mask*25,"diff",'Parent',app.UIAxes);
            
                                 if(str2double(app.vDSA.iFrame_number_chosen)== 9 && app.m_bPlotFlag)

                                    
                                   figure('Visible', 'off');
                                   plot(app.vDSA.iX_Time_Vector,app.vDSA.iY_Mean_Intensity_vector,'o',iTime_axis,dPixel_Intensities,':.');
                                    title("ROI Perfusion Intensity ");
                                    ylabel("Mean Pixel intensity");
                                    xlabel("Time [Sec]");
                                    app.m_bPlotFlag = ~app.m_bPlotFlag;
                                  save(fullfile(app.UTIL.m_sFolderPath, strcat('Graph_mean_intensity_',app.vDSA.App_Chosen_ROI,'.mat')), 'dPixel_Intensities');
                                  save(fullfile(app.UTIL.m_sFolderPath, strcat('Graph_Time_',app.vDSA.App_Chosen_ROI,'.mat')), 'iTime_axis');
                                  disp('File saved: Graph_mean_intensity.mat and Graph_Time.mat');
                                  saveas(gcf,fullfile(app.UTIL.m_sFolderPath, strcat('Graph_full_plot_',app.vDSA.App_Chosen_ROI,'.fig')));
                                  saveas(gcf,fullfile(app.UTIL.m_sFolderPath, strcat('Graph_full_plot_',app.vDSA.App_Chosen_ROI,'.png')));
                                    close(gcf);
            
                                 end
            
                            
                            if(mod(app.g_Counter_mat_detection,23) == 9)

                                    %app.vDSA=app.vDSA.Get_registered_Image(app.g_Counter_mat_detection,app.UTIL.m_sFolderPath);
                                    app.vDSA=app.vDSA.PlotArteryColorChanges();
                                 imshowpair(app.vDSA.mat_Chosen_Edge_Detection{app.g_Counter_mat_detection,1}*25,app.vDSA.m_MatColorizedArtery*100,"blend",'Parent',app.UIAxes);
                                     %imshowpair(app.vDSA.mat_Chosen_Edge_Detection{app.g_Counter_mat_detection,1}*25,app.vDSA.m_matRegistered,"blend");

                                 if(app.g_Counter_mat_detection == 193 )
                                    app.vDSA=app.vDSA.Get_registered_Image(app.g_Counter_mat_detection,app.UTIL.m_sFolderPath);
                                    imshowpair(app.vDSA.mat_Chosen_Edge_Detection{app.g_Counter_mat_detection,1}*25,app.vDSA.m_matRegistered,"blend");
                                     title('Perfusion Angio Regestraion');
                                     ylabel("Mean Pixel intensity");
                                     xlabel("Matrix 512X512 uint16");
                                     %Save reg IMG
                                    saveas(gcf,fullfile(app.UTIL.m_sFolderPath, 'Perfusion_Angio_Regestraion.fig'));
                                    saveas(gcf,fullfile(app.UTIL.m_sFolderPath, 'Perfusion_Angio_Regestraion.png'));

                                     app.vDSA=app.vDSA.Get_Reff_Images(app.vDSA.App_Chosen_seris,app.g_Counter_mat_detection,app.UTIL.m_sFolderPath);
                                        % figure;
                                        % subplot(3,2,1);
                                            imshowpair(app.vDSA.mat_Chosen_Edge_Detection{app.g_Counter_mat_detection,1}*25,app.vDSA.ROI_Mask*25,"diff");
                                            title('ROI Prefusion Based Registration');
                                            ylabel("Mean Pixel intensity");
                                            xlabel("Matrix 512X512 uint16");
                                            saveas(gcf,fullfile(app.UTIL.m_sFolderPath, strcat('Analys_Data_',app.vDSA.App_Chosen_ROI,'.fig')));
                                            saveas(gcf,fullfile(app.UTIL.m_sFolderPath, strcat('Analys_Data_',app.vDSA.App_Chosen_ROI,'_png.png')));
                                            % 
                                            % if(~isempty(app.vDSA.m_matANGIO_REFF))% Cond: To start Registeration
                                            %     subplot(3,2,2);
                                            %     imshowpair(app.vDSA.mat_Chosen_Edge_Detection{app.g_Counter_mat_detection,1}*25,app.vDSA.m_matRegistered,"blend");
                                            %     title('Registration Angio - Perfusion');
                                            % end
                                        %subplot(3,2,3);
                                         %   imshow(app.vDSA.m_matHead_Perfusion_REFF);
                                          %  title('Head Perfusion REFF');

                                        %subplot(3,2,4);
                                            %imshow(app.vDSA.m_matANGIO_REFF);
                                            %title('Angio REFF');

                                        % subplot(3,2,[5 6]);
                                        %     load(fullfile(app.UTIL.m_sFolderPath, strcat('Graph_mean_intensity_',app.vDSA.App_Chosen_ROI,'.mat')),'dPixel_Intensities');
                                        %     load(fullfile(app.UTIL.m_sFolderPath, strcat('Graph_Time_',app.vDSA.App_Chosen_ROI,'.mat')), 'iTime_axis');
                                        %     plot(iTime_axis,dPixel_Intensities);
                                        %     title('PK - Perfusion Graph');
                                        % 


                                         % saveas(gcf,fullfile(app.UTIL.m_sFolderPath, strcat('Analys_Data_',app.vDSA.App_Chosen_ROI,'.fig')));
                                         %  saveas(gcf,fullfile(app.UTIL.m_sFolderPath, strcat('Analys_Data_',app.vDSA.App_Chosen_ROI,'_png.png')));
                                         %  disp('File saved : Analys_Data.fig');
                                         %  disp('File saved : Analys_Data_png.png');

                                          close(gcf);

                                         imshowpair(app.vDSA.mat_Chosen_Edge_Detection{app.g_Counter_mat_detection,1}*25,app.vDSA.ROI_Mask*25,"diff");
                                         saveas(gcf,fullfile(app.UTIL.m_sFolderPath, strcat('ROI_',app.vDSA.App_Chosen_ROI,'.fig')));


                                 end
                            end

                         case 'ANGIO  F_0.7 iMAR'
                              imshowpair(app.vDSA.mat_Chosen_Edge_Detection{app.g_Counter_mat_detection,1}*25,app.vDSA.ROI_Mask*25,"diff",'Parent',app.UIAxes);
                                 if(app.g_Counter_mat_detection == 357)
                                        
                                   app.vDSA=app.vDSA.Get_Reff_Images(app.vDSA.App_Chosen_seris,app.g_Counter_mat_detection,app.UTIL.m_sFolderPath);

                                 end
                            
                            %end
                                    
                            %%end
                                
                                %imshow(img_obj.CData,'Parent',app.UIAxes);
                               %imshow(app.vDSA.mat_Chosen_Edge_Detection{app.g_Counter_mat_detection,1}*25,'Parent',app.UIAxes);
                                %if(app.bFlag_interpulation)
                                    
                                %else
            %                        imshow(app.vDSA.mat_Chosen_Edge_Detection{app.g_Counter_mat_detection,1},'Parent',app.UIAxes);
             %                   end
                            case 'original'
                                
                                    imshow(app.vDSA.mat_Chosen_Edge_Detection{app.g_Counter_mat_detection,1}*20,'Parent',app.UIAxes);
            
                            otherwise
            
                                  imshowpair(app.vDSA.mat_Chosen_Edge_Detection{app.g_Counter_mat_detection,1},app.vDSA.ROI_Mask*25,"diff",'Parent',app.UIAxes);
            
                        
                     end


                     case 'F_1938'
                     switch app.vDSA.App_Chosen_seris 
                            case 'HEAD PERFUSION'
            
                                iTime_axis = 0:(app.vDSA.dStep_inteval_PERFUSION):30;
                                 dPixel_Intensities = interp1(app.vDSA.iX_Time_Vector, app.vDSA.iY_Mean_Intensity_vector,iTime_axis, 'linear');
                                 xlabel(app.UIAxes2,'Time (sec)');
                                 ylabel(app.UIAxes2,'Mean Intensity ')
                                 %xlim([0 30]);
                                 plot(app.UIAxes2,app.vDSA.iX_Time_Vector,app.vDSA.iY_Mean_Intensity_vector,'o',iTime_axis,dPixel_Intensities,':.');
                                 app.bFlag_interpulation = false;

                                 %imshowpair(app.vDSA.mat_Chosen_Edge_Detection{app.g_Counter_mat_detection,1}*25,app.vDSA.ROI_Mask*25,"diff",'Parent',app.UIAxes);
            
                                 if(str2double(app.vDSA.iFrame_number_chosen)== 9 && app.m_bPlotFlag)

                                    
                                   figure('Visible', 'off');
                                   plot(app.vDSA.iX_Time_Vector,app.vDSA.iY_Mean_Intensity_vector,'o',iTime_axis,dPixel_Intensities,':.');
                                    title("ROI Perfusion Intensity ");
                                    ylabel("Mean Pixel intensity");
                                    xlabel("Time [Sec]");
                                    app.m_bPlotFlag = ~app.m_bPlotFlag;
                                  save(fullfile(app.UTIL.m_sFolderPath, strcat('Graph_mean_intensity_',app.vDSA.App_Chosen_ROI,'.mat')), 'dPixel_Intensities');
                                  save(fullfile(app.UTIL.m_sFolderPath, strcat('Graph_Time_',app.vDSA.App_Chosen_ROI,'.mat')), 'iTime_axis');
                                  disp('File saved: Graph_mean_intensity.mat and Graph_Time.mat');
                                  saveas(gcf,fullfile(app.UTIL.m_sFolderPath, strcat('Graph_full_plot_',app.vDSA.App_Chosen_ROI,'.fig')));
                                  saveas(gcf,fullfile(app.UTIL.m_sFolderPath, strcat('Graph_full_plot_',app.vDSA.App_Chosen_ROI,'.png')));
                                    close(gcf);
            
                                 end
            
                            
                            %if(mod(app.g_Counter_mat_detection,23) == 9)

                                    %app.vDSA=app.vDSA.PlotArteryColorChanges();
                                 imshowpair(app.vDSA.mat_Chosen_Edge_Detection{app.g_Counter_mat_detection,1}*25,app.vDSA.ROI_Mask*100,"blend",'Parent',app.UIAxes);

                                 if(app.g_Counter_mat_detection == 307 )
                                    app.vDSA=app.vDSA.Get_registered_Image(app.g_Counter_mat_detection,app.UTIL.m_sFolderPath);
                                    imshowpair(app.vDSA.mat_Chosen_Edge_Detection{app.g_Counter_mat_detection,1}*25,app.vDSA.m_matRegistered,"blend");
                                     title('Perfusion Angio Regestraion');
                                     ylabel("Mean Pixel intensity");
                                     xlabel("Matrix 512X512 uint16");
                                     %Save reg IMG
                                    saveas(gcf,fullfile(app.UTIL.m_sFolderPath, 'Perfusion_Angio_Regestraion.fig'));
                                    saveas(gcf,fullfile(app.UTIL.m_sFolderPath, 'Perfusion_Angio_Regestraion.png'));

                                     app.vDSA=app.vDSA.Get_Reff_Images(app.vDSA.App_Chosen_seris,app.g_Counter_mat_detection,app.UTIL.m_sFolderPath);

                                            imshowpair(app.vDSA.mat_Chosen_Edge_Detection{app.g_Counter_mat_detection,1}*25,app.vDSA.ROI_Mask*25,"diff");
                                            title('ROI Prefusion Based Registration');
                                            ylabel("Mean Pixel intensity");
                                            xlabel("Matrix 512X512 uint16");
                                            saveas(gcf,fullfile(app.UTIL.m_sFolderPath, strcat('Analys_Data_',app.vDSA.App_Chosen_ROI,'.fig')));
                                            saveas(gcf,fullfile(app.UTIL.m_sFolderPath, strcat('Analys_Data_',app.vDSA.App_Chosen_ROI,'_png.png')));

                                          close(gcf);

                                         imshowpair(app.vDSA.mat_Chosen_Edge_Detection{app.g_Counter_mat_detection,1}*25,app.vDSA.ROI_Mask*25,"diff");
                                         saveas(gcf,fullfile(app.UTIL.m_sFolderPath, strcat('ROI_',app.vDSA.App_Chosen_ROI,'.fig')));


                                 end
                            %end

                         case 'ANGIO  F_0.7 iMAR'
                              imshowpair(app.vDSA.mat_Chosen_Edge_Detection{app.g_Counter_mat_detection,1}*25,app.vDSA.ROI_Mask*25,"diff",'Parent',app.UIAxes);
                                 if(app.g_Counter_mat_detection == 328)
                                        
                                   app.vDSA=app.vDSA.Get_Reff_Images(app.vDSA.App_Chosen_seris,app.g_Counter_mat_detection,app.UTIL.m_sFolderPath);

                                 end
                            
                            %end
                                    
                            %%end
                                
                                %imshow(img_obj.CData,'Parent',app.UIAxes);
                               %imshow(app.vDSA.mat_Chosen_Edge_Detection{app.g_Counter_mat_detection,1}*25,'Parent',app.UIAxes);
                                %if(app.bFlag_interpulation)
                                    
                                %else
            %                        imshow(app.vDSA.mat_Chosen_Edge_Detection{app.g_Counter_mat_detection,1},'Parent',app.UIAxes);
             %                   end
                            case 'original'
                                
                                    imshow(app.vDSA.mat_Chosen_Edge_Detection{app.g_Counter_mat_detection,1}*20,'Parent',app.UIAxes);
            
                            otherwise
            
                                  imshowpair(app.vDSA.mat_Chosen_Edge_Detection{app.g_Counter_mat_detection,1},app.vDSA.ROI_Mask*25,"diff",'Parent',app.UIAxes);
            
                        
                   end


              end
        end

    end
    methods (Access = public)
        
        function results = ROI_HEAD_PERFUSION(app)

            %app.vDSA.vec_ROI_pos_data=app.vDSA.Draw_ROI();
            app.vDSA.sFull_Path_chosen_seg__roi = app.vDSA.Draw_ROI();
            app.Load_analysis_handle=app.UTIL_Load.Get_Load_Box_handle();
            while app.vDSA.count_analysys_img <= app.vDSA.iRow_Location_in_table


                app.vDSA.ROI_Mask = app.vDSA.Get_Mask_img(app.UTIL.m_sFolderPath);
                app.vDSA.Global_image_threshold= app.vDSA.Get_optimum_Thresh();
                app.UTIL_Load.Porcess_load((app.vDSA.count_analysys_img/app.vDSA.iRow_Location_in_table),app.Load_analysis_handle,'Loading analyised ROI');
                app.Edge_Detection();


                if (mod(app.vDSA.iSet_Step_Counter,app.vDSA.iRepiptive_frame_PERFUSION) == 0)
                    app.vDSA.iSet_Counter = app.vDSA.iSet_Counter +1;
                    
                end

                app.vDSA.count_analysys_img = app.vDSA.count_analysys_img+1;
                app.vDSA.iSet_Step_Counter = app.vDSA.iSet_Step_Counter+1;


            end
            sFull_path_template=strcat('Perfusion_data_',app.vDSA.App_Chosen_ROI,'.xlsx');
            writetable(app.vDSA.table_Perfusion,fullfile(app.UTIL.m_sFolderPath,  sFull_path_template));
            app.vDSA.count_analysys_img = 1;
            app.UTIL_Load.Close_Load_ny_handle(app.Load_analysis_handle);
            app.PlotImage.value = "Canny_Detection";

        end
        
        function results = ROI_Default(app)

             %app.vDSA.vec_ROI_pos_data=app.vDSA.Draw_ROI();
             app.vDSA.sFull_Path_chosen_seg__roi = app.vDSA.Draw_ROI();
          %   app.vDSA.segmentImage();
            app.Load_analysis_handle=app.UTIL_Load.Get_Load_Box_handle();
            while app.vDSA.count_analysys_img ~= app.vDSA.iRow_Location_in_table

                
                app.vDSA.ROI_Mask = app.vDSA.Get_Mask_img(app.UTIL.m_sFolderPath);
                app.vDSA.Global_image_threshold= app.vDSA.Get_optimum_Thresh();
                app.UTIL_Load.Porcess_load((app.vDSA.count_analysys_img/app.vDSA.iRow_Location_in_table),app.Load_analysis_handle,'Loading analyised ROI');
                app.Edge_Detection();
                app.vDSA.count_analysys_img = app.vDSA.count_analysys_img+1;
            end
                
            app.vDSA.count_analysys_img = 1;
            app.UTIL_Load.Close_Load_ny_handle(app.Load_analysis_handle);
            app.PlotImage.value = "Canny_Detection";

        end

        function results = ROI_original(app)
           
            %app.vDSA.vec_ROI_pos_data=app.vDSA.Draw_ROI();
            app.Load_analysis_handle=app.UTIL_Load.Get_Load_Box_handle();
            while app.vDSA.count_analysys_img ~= app.vDSA.iRow_Location_in_table

                
                app.vDSA.ROI_Mask = app.vDSA.Get_Mask_img(app.UTIL.m_sFolderPath);
                app.vDSA.Global_image_threshold= app.vDSA.Get_optimum_Thresh();
                app.UTIL_Load.Porcess_load((app.vDSA.count_analysys_img/app.vDSA.iRow_Location_in_table),app.Load_analysis_handle,'Loading analyised ROI');
                app.vDSA.mat_Chosen_Edge_Detection{app.vDSA.count_analysys_img,1} = app.vDSA.ROI_Mask;
                app.vDSA.count_analysys_img = app.vDSA.count_analysys_img+1;
            end
                
            app.vDSA.count_analysys_img = 1;
            app.UTIL_Load.Close_Load_ny_handle(app.Load_analysis_handle);
            app.PlotImage.value = "Original_Detection";


        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        app.vDSA.Table_Patient_Name=app.vDSA.Get_Patient_Table_Names();
        app.CoosePatientDropDown.Items=app.vDSA.Table_Patient_Name;
        app.vDSA.Table_Dicom_Full_Data = app.vDSA.Create_Table_Data();  
        imageToDisplay = imread('HIT_logo.jpg');
        imshow(imageToDisplay, 'Parent', app.UIAxes);
        app.UIAxes.Visible ="off";
        app.ChooseSerisDropDown.Items=app.vDSA.Get_Table_Dicom();
        app.ROIDropDown.Items = app.vDSA.Get_List_ROI();
        %app.vDSA.App_Chosen_ROI = app.ROIDropDown.Items{1,1};
        app.UITable.Data = {'Wait for Selection'};
        app.vDSA.iFrame_number_chosen=app.FrameNoDropDown.Value;
        app.vDSA.bFlag_Roi_saved_in_csv= false;
        app.vDSA.BFlag_new_ROI = false;
        

        end

        % Drop down opening function: ChooseSerisDropDown
        function ChooseSerisDropDownOpening(app, event)
            
            %app.CT_Data.Table_Dicom_Full_Data
        end

        % Clicked callback: ChooseSerisDropDown
        function ChooseSerisDropDownClicked(app, event)
            if(~isempty(app.vDSA.App_Chosen_ROI))
            item = event.InteractionInformation.Item;
            app.vDSA.App_Chosen_seris=event.Source.Value;
            app.main_process();
            end
            

        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            % indices = event.Indices;
            % newData = event.NewData;
           app.vDSA.arr_sTable_display =app.vDSA.Get_Table_Display(); 
            app.UITable.Data = app.vDSA.arr_sTable_display;

            
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.startupFcn();
        end

        % Button pushed function: NewROIButton
        function NewROIButtonPushed(app, event)

            switch app.vDSA.App_Chosen_seris
                case 'ANGIO  F_0.7 iMAR'

                    if (~isempty(app.vDSA.mat_sdicom_Images{325,1}))
                        app.vDSA.BFlag_new_ROI= true;
                        
           % app.vDSA.Img_present_roi = app.vDSA.mat_Chosen_Edge_Detection{325,1}*25;
                         app.vDSA.Img_present_roi=app.vDSA.mat_sdicom_Images{325,1}*25;
                        app.ROI_Default();
                         app.UTIL_Dialog.Prompt_user_mssage();

                    end

                  case 'HEAD PERFUSION'
                      % montage(app.vDSA.mat_sdicom_Images{325,1}*25,app.vDSA.mat_sdicom_Images{194,1}*25);


                        if(~isempty(app.vDSA.m_matRegistered))
                        app.vDSA.BFlag_new_ROI= true;
                       % app.vDSA.Img_present_roi=app.vDSA.mat_sdicom_Images{194,1}*25;
                       app.vDSA.Img_present_roi=app.vDSA.m_matRegistered;
                       app.ROI_Default();
                       app.UTIL_Dialog.Prompt_user_mssage();
                        end


             %switch app.vDSA.App_Chosen_seris
              %  case 'HEAD PERFUSION'
               %
               %app.Plot();


            %end
          %  if(~isempty(app.vDSA.App_Chosen_seris))
          %  app.Process_after_Dicom_load();

            %else
            %    app.startupFcn();
           % end
            end
        end

        % Button pushed function: Next
        function NextPushed(app, event)
            if(app.g_Counter_mat_detection < app.vDSA.iRow_Location_in_table)
            app.g_Counter_mat_detection = app.g_Counter_mat_detection +1;
            app.FrameNoEditFieldValueChanged();
            app.Plot_Image();
            end

        end

        % Button pushed function: Previous
        function PreviousPushed(app, event)
            if(app.g_Counter_mat_detection >1)
                app.g_Counter_mat_detection = app.g_Counter_mat_detection -1;
                app.FrameNoEditFieldValueChanged();
                app.Plot_Image();
            end

        end

        % Button pushed function: Double_Next
        function Double_NextPushed(app, event)
           
            while(app.g_Counter_mat_detection < app.vDSA.iRow_Location_in_table)%max_frames
                    app.g_Counter_mat_detection = app.g_Counter_mat_detection +1;
                    app.FrameNoEditFieldValueChanged();
                    app.Plot_Image();
                    pause(app.step);
                 
            end
        end

        % Button pushed function: Double_previous
        function Double_previousPushed(app, event)
            
            while(app.g_Counter_mat_detection >1)
                app.g_Counter_mat_detection = app.g_Counter_mat_detection -1;
                app.FrameNoEditFieldValueChanged();
                 app.Plot_Image();
                 pause(app.step);
            end
        end

        % Callback function
        function EnterFrameDurationsecEditFieldValueChanged(app, event)
            value = app.EnterFrameDurationsecEditField.Value;
        
            
        end

        % Value changed function: EnterFPSEditField
        function EnterFPSEditFieldValueChanged(app, event)
            value = app.EnterFPSEditField.Value;
            app.step=app.UTIL_freq.Set_Time_Frequency(1,value);

        end

        % Callback function
        function ShowROIButtonPushed(app, event)
            if (~isempty(app.vDSA.ROI_Mask))
                app.PlotImage.value = "ROI_Mask";
                app.Plot_Image();

            end
        end

        % Value changed function: FrameNoEditField
        function FrameNoEditFieldValueChanged(app, event)
            app.FrameNoEditField.Value = app.g_Counter_mat_detection;
            
        end

        % Button pushed function: Pause
        function PauseButtonPushed(app, event)
            app.FrameNoEditFieldValueChanged();
            app.Plot_Image();
            pause();
        end

        % Value changed function: FrameNoDropDown
        function FrameNoDropDownValueChanged(app, event)
            app.vDSA.iFrame_number_chosen = app.FrameNoDropDown.Value;
            switch app.vDSA.App_Chosen_seris
                case 'HEAD PERFUSION'
                    app.Plot();
                    
                    
            end
        end

        % Value changed function: DetectionDropDown
        function DetectionDropDownValueChanged(app, event)
            if(~isempty(app.vDSA.App_Chosen_seris))
            value = app.DetectionDropDown.Value;
            app.vDSA.App_Chosen_seris=value;
            app.ROI_Of_Dicom();
            app.Plot();
            end
        end

        % Drop down opening function: ROIDropDown
        function ROIDropDownOpening(app, event)
            
            
        end

        % Value changed function: ROIDropDown
        function ROIDropDownValueChanged(app, event)
           % value = app.ROIDropDown.Value;
            %app.vDSA.App_Chosen_ROI=value;
        end

        % Clicked callback: ROIDropDown
        function ROIDropDownClicked(app, event)
            item = event.InteractionInformation.Item;
            app.vDSA.App_Chosen_ROI=event.Source.Value;
        end

        % Drop down opening function: CoosePatientDropDown
        function CoosePatientDropDownOpening(app, event)
            
        end

        % Clicked callback: CoosePatientDropDown
        function CoosePatientDropDownClicked(app, event)
            item = event.InteractionInformation.Item;
            app.vDSA.App_Choose_Patient = event.Source.Value;
            app.m_sFolerPlotName = strcat(app.vDSA.App_Choose_Patient,'_','Plots');
            app.UTIL.m_sFolderPath =  app.m_sFolerPlotName;
            app.UTIL = app.UTIL.Create_Plot_Folder(app.m_sFolerPlotName);
            %app.vDSA=app.vDSA.Set_Foler_Plot_Name_Path(app.m_sFolerPlotName);
            app.startupFcn();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.ChooseSerisDropDownLabel = uilabel(app.UIFigure);
            app.ChooseSerisDropDownLabel.Text = 'Choose Seris';
            app.ChooseSerisDropDown = uidropdown(app.UIFigure);
            app.ChooseSerisDropDown.Items = {'Choose seris'};
            app.ChooseSerisDropDown.DropDownOpeningFcn = createCallbackFcn(app, @ChooseSerisDropDownOpening, true);
            app.ChooseSerisDropDown.ClickedFcn = createCallbackFcn(app, @ChooseSerisDropDownClicked, true);
            app.ChooseSerisDropDown.Value = 'Choose seris';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'SeriesDateTime'; 'PatientName'; 'PatientSex'; 'Frames'};
            app.UITable.RowName = {'1'};
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.NewROIButton = uibutton(app.UIFigure, 'push');
            app.NewROIButton.ButtonPushedFcn = createCallbackFcn(app, @NewROIButtonPushed, true);
            app.NewROIButton.Text = 'New ROI';
            app.Double_Next = uibutton(app.UIFigure, 'push');
            app.Double_Next.ButtonPushedFcn = createCallbackFcn(app, @Double_NextPushed, true);
            app.Double_Next.Text = '>>';
            app.Double_previous = uibutton(app.UIFigure, 'push');
            app.Double_previous.ButtonPushedFcn = createCallbackFcn(app, @Double_previousPushed, true);
            app.Double_previous.Text = '<<';
            app.Next = uibutton(app.UIFigure, 'push');
            app.Next.ButtonPushedFcn = createCallbackFcn(app, @NextPushed, true);
            app.Next.Text = '>';
            app.Previous = uibutton(app.UIFigure, 'push');
            app.Previous.ButtonPushedFcn = createCallbackFcn(app, @PreviousPushed, true);
            app.Previous.Text = '<';
            app.EnterFPSEditFieldLabel = uilabel(app.UIFigure);
            app.EnterFPSEditFieldLabel.Text = 'Enter FPS';
            app.EnterFPSEditField = uieditfield(app.UIFigure, 'numeric');
            app.EnterFPSEditField.ValueChangedFcn = createCallbackFcn(app, @EnterFPSEditFieldValueChanged, true);
            app.FrameNoEditFieldLabel = uilabel(app.UIFigure);
            app.FrameNoEditFieldLabel.Text = 'Frame No''';
            app.FrameNoEditField = uieditfield(app.UIFigure, 'numeric');
            app.FrameNoEditField.ValueChangedFcn = createCallbackFcn(app, @FrameNoEditFieldValueChanged, true);
            app.Pause = uibutton(app.UIFigure, 'push');
            app.Pause.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.Pause.Text = '||';
            app.FrameNoDropDownLabel = uilabel(app.UIFigure);
            app.FrameNoDropDownLabel.Text = 'Frame No''';
            app.FrameNoDropDown = uidropdown(app.UIFigure);
            app.FrameNoDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23'};
            app.FrameNoDropDown.ValueChangedFcn = createCallbackFcn(app, @FrameNoDropDownValueChanged, true);
            app.FrameNoDropDown.Value = '1';
            app.DetectionDropDownLabel = uilabel(app.UIFigure);
            app.DetectionDropDownLabel.Text = 'Detection';
            app.DetectionDropDown = uidropdown(app.UIFigure);
            app.DetectionDropDown.Items = {'Select Detection', 'original'};
            app.DetectionDropDown.ValueChangedFcn = createCallbackFcn(app, @DetectionDropDownValueChanged, true);
            app.DetectionDropDown.Value = 'Select Detection';
            app.ROIDropDownLabel = uilabel(app.UIFigure);
            app.ROIDropDownLabel.Text = 'ROI';
            app.ROIDropDown = uidropdown(app.UIFigure);
            app.ROIDropDown.DropDownOpeningFcn = createCallbackFcn(app, @ROIDropDownOpening, true);
            app.ROIDropDown.ValueChangedFcn = createCallbackFcn(app, @ROIDropDownValueChanged, true);
            app.ROIDropDown.ClickedFcn = createCallbackFcn(app, @ROIDropDownClicked, true);
            app.CoosePatientDropDownLabel = uilabel(app.UIFigure);
            app.CoosePatientDropDownLabel.Text = 'Coose Patient';
            app.CoosePatientDropDown = uidropdown(app.UIFigure);
            app.CoosePatientDropDown.DropDownOpeningFcn = createCallbackFcn(app, @CoosePatientDropDownOpening, true);
            app.CoosePatientDropDown.ClickedFcn = createCallbackFcn(app, @CoosePatientDropDownClicked, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Virtual_CT

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end